classdef dImaproce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_6       matlab.ui.container.Menu
        jpgMenu      matlab.ui.container.Menu
        pngMenu      matlab.ui.container.Menu
        Menu_7       matlab.ui.container.Menu
        Menu_11      matlab.ui.container.Menu
        Menu_12      matlab.ui.container.Menu
        Menu_8       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_10      matlab.ui.container.Menu
        RightPanel   matlab.ui.container.Panel
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Hyperlink    matlab.ui.control.Hyperlink
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            % Allow user to select an image file
            [filename, filepath] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', 'Image Files'}, 'Select Image');
    
            % Check if the user selected a file
            if isequal(filename, 0)
                return; % User canceled the file selection
            end
    
            % Construct the full file path
            ImagePath = fullfile(filepath, filename);
    
            % Read the selected image
            selectedImage = imread(ImagePath);
    
            % Display the image in the Axes component
            imshow(selectedImage, 'Parent', app.UIAxes);

            % Display image path in EditField
            app.EditField.Value = ImagePath;
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            % Close the application
            delete(app);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            % 检查是否存在打开的图像
            if ~isempty(app.UIAxes)
                % 关闭打开的图像
                cla(app.UIAxes); % 清空 UIAxes
                app.UIAxes = uiaxes(app.RightPanel);
                title(app.UIAxes, '  ')
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.ZTick = [];
                app.UIAxes.Box = 'on';
                app.UIAxes.Position = [70 240 250 250];
                app.EditField.Value = '';
            else
                % 提示用户没有打开的图像
                uialert(app.UIFigure, '没有打开的图片。', '提示', 'Icon', 'warning');
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            str_help = ['Click "选择图片", select the image where you want to obtain the number and amount of coins, ' ...
                'select coin detection and counting in "选择", and finally click "开始执行". ' ...
                'You can choose the save format in "文件".'];
            msgbox(str_help,'提示信息');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
            try
                img = imread(value);
                imshow(img, 'Parent', app.UIAxes);
            catch
                % 处理读取图像失败的情况
                errordlg('无法读取图像。请检查文件路径。', '错误');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '关闭';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.Text = '导出为';
            app.jpgMenu = uimenu(app.Menu_6);
            app.jpgMenu.Text = '导出为.jpg文件';
            app.pngMenu = uimenu(app.Menu_6);
            app.pngMenu.Text = '导出为.png文件';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '退出';
            app.Menu_11 = uimenu(app.UIFigure);
            app.Menu_11.Text = '编辑';
            app.Menu_12 = uimenu(app.Menu_11);
            app.Menu_12.Text = '我觉得应该有一个编辑栏在这，但是好像没什么用';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '选择';
            app.Menu_3 = uimenu(app.Menu_8);
            app.Menu_3.Text = '硬币检测及计数';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '帮助';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '原图')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '执行后')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.Text = '开始执行';
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'https://github.com/Hina-15';
            app.Hyperlink.Text = 'Learn more';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '图片路径';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '硬币数量';
            app.EditField_2 = uieditfield(app.RightPanel, 'text');
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '硬币种类';
            app.EditField_3 = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dImaproce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end